classdef mission_planning < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        MissionconfigurationTab         matlab.ui.container.Tab
        OnboardexperimentparametersLabel  matlab.ui.control.Label
        T_idleminEditField              matlab.ui.control.NumericEditField
        T_idleminEditFieldLabel         matlab.ui.control.Label
        T_offmsEditField                matlab.ui.control.NumericEditField
        T_offmsEditFieldLabel           matlab.ui.control.Label
        T_tcmsEditField                 matlab.ui.control.NumericEditField
        T_tctmmsLabel                   matlab.ui.control.Label
        T_onmsEditField                 matlab.ui.control.NumericEditField
        T_onmsEditFieldLabel            matlab.ui.control.Label
        BeamAperture                    matlab.ui.control.EditField
        BeamApertureLabel               matlab.ui.control.Label
        HourEditField                   matlab.ui.control.EditField
        HourEditFieldLabel              matlab.ui.control.Label
        DateDatePicker                  matlab.ui.control.DatePicker
        DateDatePickerLabel             matlab.ui.control.Label
        RequiredEbNoRx                  matlab.ui.control.EditField
        RequiredEbNoMbpsEditFieldLabel  matlab.ui.control.Label
        GaintoNoiseTemperatureratioRx   matlab.ui.control.EditField
        GaintoNoiseTemperatureratiodBKLabel  matlab.ui.control.Label
        PreReceiverLossRx               matlab.ui.control.EditField
        PreReceiverLossdBEditFieldLabel  matlab.ui.control.Label
        SystemLossRx                    matlab.ui.control.EditField
        SystemLossdBEditFieldLabel      matlab.ui.control.Label
        ExperimentreceiverparametersLabel  matlab.ui.control.Label
        satAntenna                      matlab.ui.control.DropDown
        AntennaDropDownLabel            matlab.ui.control.Label
        satMountingAnglesTxRx           matlab.ui.control.EditField
        MountingAnglesmLabel            matlab.ui.control.Label
        satPowerTx                      matlab.ui.control.EditField
        PowerdBWLabel                   matlab.ui.control.Label
        satBitRateTx                    matlab.ui.control.EditField
        BitRateMbpsLabel                matlab.ui.control.Label
        FrequencyHzLabel                matlab.ui.control.Label
        satFrequencyTx                  matlab.ui.control.EditField
        satMountingLocationTxRx         matlab.ui.control.EditField
        MountingLocationmLabel          matlab.ui.control.Label
        satNameTxRx                     matlab.ui.control.EditField
        NameEditFieldLabel              matlab.ui.control.Label
        ExperimenttransmitterparametersLabel  matlab.ui.control.Label
        ButtonGroup                     matlab.ui.container.ButtonGroup
        CommsDownLinkButton             matlab.ui.control.RadioButton
        CommsUpLinkButton               matlab.ui.control.RadioButton
        RunSimulationButton             matlab.ui.control.Button
        gsInfo                          matlab.ui.control.EditField
        SelectxlsxfileButton            matlab.ui.control.Button
        tleInfo                         matlab.ui.control.EditField
        SelecttlefileButton             matlab.ui.control.Button
        GroundstationsdataLabel         matlab.ui.control.Label
        OrbitalplatformdataLabel        matlab.ui.control.Label
        MissionparametersLabel          matlab.ui.control.Label
        SimulationconfigurationTab      matlab.ui.container.Tab
        ShowantennaradiationpatternsCheckBox  matlab.ui.control.CheckBox
        FocusscenarycamerainorbitalplatformCheckBox  matlab.ui.control.CheckBox
        OtherparametersLabel            matlab.ui.control.Label
        ScenaryDurationhEditField       matlab.ui.control.EditField
        ScenaryDurationhEditFieldLabel  matlab.ui.control.Label
        SampletimesEditField            matlab.ui.control.EditField
        SampletimesEditFieldLabel       matlab.ui.control.Label
        SimulationparametersLabel       matlab.ui.control.Label
        ShowgroundtrackCheckBox         matlab.ui.control.CheckBox
        SimulationrepresentationButtonGroup  matlab.ui.container.ButtonGroup
        Button_2D                       matlab.ui.control.ToggleButton
        Button_3D                       matlab.ui.control.ToggleButton
        ResultsTab                      matlab.ui.container.Tab
        GeneratemissionplanButton       matlab.ui.control.Button
        ExporttocsvfileButton           matlab.ui.control.Button
        ExporttoxlsxfileButton          matlab.ui.control.Button
        UITable                         matlab.ui.control.Table
        InformationTab                  matlab.ui.container.Tab
        SoftwareVersionv12Label         matlab.ui.control.Label
        DegreeAerospaceEngineeringinNavigationLabel  matlab.ui.control.Label
        AuthorCsarBoraoMoratinosLabel   matlab.ui.control.Label
        Image                           matlab.ui.control.Image
        PAYLOADMISSIONPLANNERLabel      matlab.ui.control.Label
        ContextMenu                     matlab.ui.container.ContextMenu
        Menu                            matlab.ui.container.Menu
        Menu2                           matlab.ui.container.Menu
    end

    properties (Access = private)
        tleFile = ''
        gsFile = ''
        raw       
        gs 
        startTime
        stopTime
        duration
    end

    methods (Access = private)

        function sc = createScenario(app)
            % Set mission and simulation configuration
            date = app.DateDatePicker.Value;
            hour = datetime(app.HourEditField.Value, 'InputFormat', 'HH:mm:ss','TimeZone','UTC');
              
            app.startTime =  date + hours(hour.Hour) + minutes(hour.Minute) + seconds(hour.Second);
            app.duration = hours(str2double(app.ScenaryDurationhEditField.Value)); %str2double(app.ScenaryDurationhEditField);
            sampleTime = str2double(app.SampletimesEditField.Value);
            app.stopTime = app.startTime + app.duration;
        
            % Create new mission scenario
            sc = satelliteScenario(app.startTime,app.stopTime,sampleTime);  
        end
        
        function sat = SatFromTle(app,sc)
            try 
                % Create satellite object
                sat = satellite(sc,app.tleFile);
                assignin('base','sat',sat)
                
                % Create if there is only one satellite
                if size(sat,2) ~= 1
                    disp(strcat('Error: File "',app.tleInfo.Value ,'" includes a satellite constellation. Program works with an unique satellite')); 
                    error("More than one satellite included in .tle file")
                end
            catch e
                fprintf(1,'Error: %s\n',e.message);
                return 
            end
        end
    
        function gs = GsFromXlsx(app,sc)
           
            % Read gs .xlsx file content
            try
                [~,~,app.raw] = xlsread(app.gsFile);
                assignin('base','raw',app.raw);
            catch e
                fprintf(1,'Error: %s\n',e.message);
                return
            end

            % Create Ground station objects
            for i = 2:size(app.raw,2)
                % Ground station creation
                gsName = app.raw{1,i};
                gsLat = app.raw{2,i};
                gsLon = app.raw{3,i};
                gsMinElevationAngle = app.raw{4,i};
                gs(i-1) = groundStation(sc,"Name",gsName,"Latitude",gsLat,"Longitude",gsLon,"MinElevationAngle",gsMinElevationAngle);
                
            end
            
            assignin('base','gs',gs)
            app.gs = gs;
        end

        function [satRFEquipment, RFtype] = addSatRFEquipment(app, sat)

            % If is Downlink calculation, equipment is Transmitter
            if app.CommsDownLinkButton.Value == true
        
                % Satellite transmitter
                satTx = transmitter(sat, ...
                                "Name", app.satNameTxRx.Value, ...
                                "MountingLocation", str2num(app.satMountingLocationTxRx.Value), ... 
                                "MountingAngles", str2num(app.satMountingAnglesTxRx.Value), ... 
                                "Frequency",str2double(app.satFrequencyTx.Value), ...    
                                "BitRate",str2double(app.satBitRateTx.Value), ...
                                "Power",str2double(app.satPowerTx.Value));  
                
                satRFEquipment = satTx;
                RFtype = "Tx";

            % If is Uplink calculation, equipment is Receiver
            elseif app.CommsUpLinkButton.Value == true
    
                % Satellite receiver
                satRx = receiver(sat, ...
                                "Name", app.satNameTxRx.Value, ...
                                "MountingLocation", str2num(app.satMountingLocationTxRx.Value), ... 
                                "MountingAngles", str2num(app.satMountingAnglesTxRx.Value), ...
                                "RequiredEbNo", str2double(app.RequiredEbNoRx.Value), ...
                                "SystemLoss", str2double(app.SystemLossRx.Value), ...
                                "PreReceiverLoss", str2double(app.PreReceiverLossRx.Value), ...
                                "GainToNoiseTemperatureRatio", str2double(app.GaintoNoiseTemperatureratioRx.Value), ...
                                "RequiredEbNo", str2double(app.RequiredEbNoRx.Value));    

                satRFEquipment = satRx;
                RFtype = "Rx";

            end

        end

        function [gsRFEquipment, RFtype] = addGsRFEquipment(app, gs, sat)

            % If is Downlink calculation, equipment is Receiver
            if app.CommsDownLinkButton.Value == true

                % Add receiver for each gs
                for i = 2:size(app.raw,2)
                    
                    % Gimbal configuration
                    gimbalMountingLocation = app.raw{5,i};
                    gimbalMountingAngles = app.raw{6,i};
                    if isnan(gimbalMountingLocation)
                        current = gs(i-1);
                    else
                        gsGimbal = gimbal(gs(i-1), ...
                                        "MountingLocation",str2num(gimbalMountingLocation), ...
                                        "MountingAngles",str2num(gimbalMountingAngles));
                  
                        % Set the gimbals to track the satellite.
                        current = gsGimbal;
                        pointAt(current,sat);
                    end  
                    
                    % Antenna configuration
                    gsAntennaRx = app.raw{12,i};
                    if isnan(gsAntennaRx)
                        gsAntennaRx = 'Gaussian';
                    end

                    gsAntennaApertureRx = app.raw{13,i};
                    if isnan(gsAntennaApertureRx)
                        gsAntennaApertureRx = 70;
                    end

                    % Set receiver parameters
                    gsMountingAnglesRx = str2double(app.raw{7,i});
                    if isnan(gsMountingAnglesRx)
                        gsMountingAnglesRx = double([0;180;0]);
                    end
                    gsGainToNoiseTemperatureRatioRx = app.raw{8,i};
                    if isnan(gsGainToNoiseTemperatureRatioRx)
                        gsGainToNoiseTemperatureRatioRx = 3;
                    end
                    gsRequiredEbNoRx = app.raw{9,i};
                    if isnan(gsRequiredEbNoRx)
                        gsRequiredEbNoRx = 10;
                    end
                    gsReceiverSystemLossRx = app.raw{10,i};
                    if isnan(gsReceiverSystemLossRx)
                        gsReceiverSystemLossRx = 5;
                    end
                    gsPreReceiverLossRx = app.raw{11,i};
                    if isnan(gsPreReceiverLossRx)
                        gsPreReceiverLossRx = 3;
                    end
                    

                    % Create receiver object
                    gsRx(i-1) = receiver(current, ...
                                    "Name", strcat(gs(i-1).Name, ' Rx'), ...
                                    "GainToNoiseTemperatureRatio",gsGainToNoiseTemperatureRatioRx, ... 
                                    "RequiredEbNo",gsRequiredEbNoRx, ...
                                    "SystemLoss",gsReceiverSystemLossRx,...
                                    "PreReceiverLoss",gsPreReceiverLossRx); 
                    if isnan(gimbalMountingLocation)
                        gsRx(i-1).MountingAngles = gsMountingAnglesRx;
                    end

                    % Configure Antenna properties
                    if strcmp(gsAntennaRx,'Gaussian')
                        lambda = 3*10^8/str2double(app.satFrequencyTx.Value); 
                        [d,~] = calculateDishDiameter(app,gsAntennaApertureRx,lambda,0.65);
                        gaussianAntenna(gsRx(i-1),"DishDiameter",d);
                    end

                    gsRFEquipment(i-1) = gsRx(i-1);
                    RFtype = 'Rx';                             
                end

            % If is Uplink calculation, equipment is Transmitter
            elseif app.CommsUpLinkButton.Value == true

                % Add transmitter for each gs
                for i = 2:size(app.raw,2)

                    % Gimbal configuration
                    gimbalMountingLocation = app.raw{5,i};
                    gimbalMountingAngles = app.raw{6,i};
                    if isnan(gimbalMountingLocation)
                        current = gs(i-1);
                    else
                        gsGimbal = gimbal(gs(i-1), ...
                                    "MountingLocation",str2num(gimbalMountingLocation), ...
                                    "MountingAngles",str2num(gimbalMountingAngles));
                    
                        % Set the gimbals to track the satellite.
                        current = gsGimbal;
                        pointAt(current,sat);
                    end 

                    % Antenna configuration
                    gsAntennaTx = str2num(app.raw{12,i});
                    if isnan(gsAntennaTx)
                        gsAntennaTx = 'Gaussian';
                    end

                    gsAntennaApertureTx = app.raw{13,i};
                    if isnan(gsAntennaApertureTx)
                        gsAntennaApertureTx = 70;
                    end  

                    % Set transmitter configuration
                    gsMountingAnglesTx = str2double(app.raw{7,i});
                    if isnan(gsMountingAnglesTx)
                        gsMountingAnglesTx = double([0;180;0]);
                    end
                    gsSystemLossTx = app.raw{14,i};
                    if isnan(gsSystemLossTx)
                        gsSystemLossTx = 5;
                    end
                    gsFrequencyTx = app.raw{15,i};
                    if isnan(gsFrequencyTx)
                        gsFrequencyTx = 14e9;
                    end
                    gsBitRateTx = app.raw{16,i};
                    if isnan(gsBitRateTx)
                        gsBitRateTx = 10;
                    end
                    gsPowerTx = app.raw{17,i};
                    if isnan(gsPowerTx)
                        gsPowerTx = 12;
                    end
    
                    % Creating transmitter
                    gsTx(i-1) = transmitter(current, ...
                                        "Name", strcat(gs(i-1).Name, ' Tx'), ...
                                        "SystemLoss",gsSystemLossTx, ... 
                                        "Frequency",gsFrequencyTx, ...
                                        "BitRate",gsBitRateTx,...
                                        "Power",gsPowerTx);                                                          
                    if isnan(gimbalMountingLocation)
                        gsTx(i-1).MountingAngles = gsMountingAnglesTx;
                    end

                    % Configure Antenna
                    if strcmp(gsAntennaTx,'Gaussian')
                        lambda = 3*10^8./gsFrequencyTx;
                        [d,~] = calculateDishDiameter(app,gsAntennaApertureTx,lambda,0.65);
                        gaussianAntenna(gsTx(i-1),"DishDiameter",d);
                    end

                    gsRFEquipment(i-1) = gsTx(i-1);
                    RFtype = 'Tx';
                end
            end      
        end

        function createLink(app, satRFEquipment, gsRFEquipment)
            % Create link
            if app.CommsDownLinkButton.Value == true     
                lnk = link(satRFEquipment,gsRFEquipment);
            elseif app.CommsUpLinkButton.Value == true
                lnk = link(gsRFEquipment,satRFEquipment);   
            end

            % show link intervals
            intervals = linkIntervals(lnk);

            % Store results in Results tab
            currentData = app.UITable.Data;
            newData = vertcat(currentData, intervals);
            app.UITable.Data = newData;
            app.ExporttoxlsxfileButton.Visible = true;
            app.ExporttocsvfileButton.Visible = true;
            app.GeneratemissionplanButton.Visible = true;

            assignin('base','UITable',app.UITable.DisplayData)
        end
        
        function [d, gain] = calculateDishDiameter(~,aperture,lambda,efficiency)
            d = (70*lambda)/aperture;
            gain = efficiency*((pi*d/lambda)^2);
        end
        
        function configureSatAntenna(app,satRFEquipment)
            if app.CommsUpLinkButton.Value == true
                if app.satAntenna.Value == "Gaussian Antenna"
                    lambda = 3*10^8/app.raw{15,2};
                    [d,~] = calculateDishDiameter(app,str2double(app.BeamAperture.Value),lambda,0.65);
                    gaussianAntenna(satRFEquipment,"DishDiameter",d); % meters
                end
            elseif app.CommsDownLinkButton.Value == true
                if app.satAntenna.Value == "Gaussian Antenna"
                    lambda = 3*10^8/str2double(app.satFrequencyTx.Value);
                    [d,~] = calculateDishDiameter(app,str2double(app.BeamAperture.Value),lambda,0.65);
                    gaussianAntenna(satRFEquipment,"DishDiameter",d); % meters
                end       
            end
        end

        function [gimbal, index] = getGimbalFromRFEquipment(app, equipment)
            for i = 1:size(app.gs, 2)
                if app.CommsUpLinkButton.Value == true
                    if any(app.gs(i).Gimbals.Transmitters.Name == equipment)
                        gimbal = app.gs(i).Gimbals;
                        index = i;
                        return;
                    elseif any(app.gs(i).Transmitters.Name == equipment)
                        gimbal = '';
                        index = i;
                        return;
                    end
                elseif app.CommsDownLinkButton.Value == true
                    if any(app.gs(i).Gimbals.Receivers.Name == equipment)
                        gimbal = app.gs(i).Gimbals;
                        index = i;
                        return;
                    elseif any(app.gs(i).Receivers.Name == equipment)
                        gimbal = '';
                        index = i;
                        return;
                    end
                end
            end
            gimbal = '';
            index = 0;
        end

%         function [gimbal, index]= getGimbalFromRFEquipment(app,equipment)
%             gimbal = '';
%             for i = 1:size(app.gs,2)
%                 if size(app.gs(i).Gimbals,2) ~= 0 && strcmp(app.gs(i).Gimbals.Receivers.Name,equipment)
%                      gimbal = app.gs(i).Gimbals;
%                      index = i;
%                      break;
%                 end
%             end
%         end


    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Result table initialization
            app.UITable.ColumnName = ["Source","Target","IntervalNumber","StartTime","EndTime","Duration","StartOrbit","EndOrbit"];
            app.UITable.Visible = true;
            app.UITable.Data = [];
            
        end

        % Button pushed function: RunSimulationButton
        function RunSimulationButtonPushed(app, event)

            % Remove previous results
            app.UITable.Data = [];

            % Create mission scenario 
            sc = createScenario(app);
           
            % Create orbital platform data from selected .tle file
            sat = SatFromTle(app,sc);
   
            % Add RF satellite equipment
            [satRFEquipment, ~] = addSatRFEquipment(app,sat);
            
            % Create ground stations data from selected .xlsx file 
            app.gs = GsFromXlsx(app, sc);
            
            % Add RF ground stations equipment
            [gsRFEquipment, ~] = addGsRFEquipment(app,app.gs,sat);
            
            configureSatAntenna(app,satRFEquipment)

            % Create Link
            createLink(app,satRFEquipment,gsRFEquipment)

            % Sorting table chronologically
            app.UITable.Data = sortrows(app.UITable.Data, 'StartTime');

            % Visualization extra options
            if app.ShowantennaradiationpatternsCheckBox.Value == true
                if app.CommsDownLinkButton.Value == true
                   pattern(satRFEquipment);
                   for i = 1:size(gsRFEquipment,2)
                       pattern(gsRFEquipment(i),satRFEquipment.Frequency);
                   end

                elseif app.CommsUpLinkButton.Value == true
                   pattern(satRFEquipment,app.raw{15,2});
                   for i = 1:size(gsRFEquipment,2)
                       pattern(gsRFEquipment(i));
                   end
                end
            end

            if app.Button_2D.Value == true
                dimension = '2D';
            else
                dimension = '3D';
            end    
            scv = satelliteScenarioViewer(sc,Dimension=dimension);
             
            if app.ShowgroundtrackCheckBox.Value == true
                groundTrack(sat,LeadTime=3600);
            end
            
            if app.FocusscenarycamerainorbitalplatformCheckBox.Value == true
                camtarget(scv,sat);
            end

        end

        % Button pushed function: SelecttlefileButton
        function SelecttlefileButtonPushed(app, event)
            % Get file
            [fileName,filePath] = uigetfile();

            % Check file has name
            if fileName ~= 0
                fullFileName = fullfile(filePath,fileName);
                [~,fileNameNoExt,fileExt] = fileparts(fullFileName);

                % Check file extension is .tle
                if strcmp(fileExt,'.tle')
                    app.tleFile = [fileNameNoExt fileExt];
                    assignin('base','tleSelectedFile',fullFileName);
                    app.tleInfo.Value = app.tleFile; 

                    if ~isempty(app.gsFile)
                        app.RunSimulationButton.Visible = true;
                    end
                else
                    app.tleInfo.Value = 'Error: selected file is not .tle';
                    app.RunSimulationButton.Visible = false;
                end
            end
        end

        % Button pushed function: SelectxlsxfileButton
        function SelectxlsxfileButtonPushed(app, event)
            % Get file
            [fileName,filePath] = uigetfile();

            % Check file has name
            if fileName ~= 0
                fullFileName = fullfile(filePath,fileName);
                [~,fileNameNoExt,fileExt] = fileparts(fullFileName);

                % Check file extension is .xlsx
                if strcmp(fileExt,'.xlsx')
                    app.gsFile= [fileNameNoExt fileExt];
                    app.gsInfo.Value = app.gsFile;
                    assignin('base','gsSelectedFile',fullFileName);

                    if ~isempty(app.tleFile)
                        app.RunSimulationButton.Visible = true;
                    end
                else
                    app.gsInfo.Value = 'Error: selected file is not .xlsx';
                    app.RunSimulationButton.Visible = false;
                end
            end            
        end

        % Button down function: ButtonGroup
        function ButtonGroupButtonDown(app, event)
            if app.CommsDownLinkButton.Value == true
                app.NameEditFieldLabel.Visible = true;
                app.satNameTxRx.Visible = true;
            end  
        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)
%             selectedButton = app.ButtonGroup.SelectedObject;
            if app.CommsDownLinkButton.Value == true
                app.ExperimenttransmitterparametersLabel.Visible = true;
                app.ExperimentreceiverparametersLabel.Visible = false;

                app.satFrequencyTx.Visible = true;
                app.FrequencyHzLabel.Visible = true;
                app.satBitRateTx.Visible = true;
                app.BitRateMbpsLabel.Visible = true;
                app.satPowerTx.Visible = true;
                app.PowerdBWLabel.Visible = true;
               
                app.SystemLossRx.Visible = false;
                app.SystemLossdBEditFieldLabel.Visible = false;
                app.PreReceiverLossRx.Visible = false;
                app.PreReceiverLossdBEditFieldLabel.Visible = false;
                app.GaintoNoiseTemperatureratioRx.Visible = false;
                app.GaintoNoiseTemperatureratiodBKLabel.Visible = false;
                app.RequiredEbNoRx.Visible = false;
                app.RequiredEbNoMbpsEditFieldLabel.Visible = false;

            end 
            if app.CommsUpLinkButton.Value == true
                app.ExperimenttransmitterparametersLabel.Visible = false;
                app.ExperimentreceiverparametersLabel.Visible = true;

                app.satFrequencyTx.Visible = false;
                app.FrequencyHzLabel.Visible = false;
                app.satBitRateTx.Visible = false;
                app.BitRateMbpsLabel.Visible = false;
                app.satPowerTx.Visible = false;
                app.PowerdBWLabel.Visible = false;

                app.SystemLossRx.Visible = true;
                app.SystemLossdBEditFieldLabel.Visible = true;
                app.PreReceiverLossRx.Visible = true;
                app.PreReceiverLossdBEditFieldLabel.Visible = true;
                app.GaintoNoiseTemperatureratioRx.Visible = true;
                app.GaintoNoiseTemperatureratiodBKLabel.Visible = true;
                app.RequiredEbNoRx.Visible = true; 
                app.RequiredEbNoMbpsEditFieldLabel.Visible = true;
                
            end   
        end

        % Button pushed function: ExporttoxlsxfileButton
        function ExporttoxlsxfileButtonPushed(app, event)
            [fileName, pathName] = uiputfile('*.xlsx','Save as');
            if fileName == 0
                return;
            end
            fullPath = fullfile(pathName, fileName);
            writetable(app.UITable.Data, fullPath, "AutoFitWidth",false);

        end

        % Button pushed function: ExporttocsvfileButton
        function ExporttocsvfileButtonPushed(app, event)
            [file, path] = uiputfile('*.csv', 'Save as');
            if file == 0
                return
            end
            
            filename = fullfile(path, file);
            writetable(app.UITable.Data, filename);
            
        end

        % Button pushed function: GeneratemissionplanButton
        function GeneratemissionplanButtonPushed(app, event)
            [file, path] = uiputfile('*.txt', 'Save as');
            if file == 0
                return
            end
            
            fileId = fopen(fullfile(path, file), 'w');
            fprintf(fileId,'--------------------------------------------------------------------------------------\n');
            fprintf(fileId,'MISSION PLAN\n--------------------------------------------------------------------------------------\n\n');
            fprintf(fileId,'Generation date: %s\n',datetime("now"));
            fprintf(fileId,'\n--------------------------------------------------------------------------------------\n');
            fprintf(fileId,'Mission Parameters: \n\n');
            fprintf(fileId,'\tUTC Start Time: %s\n',app.startTime);
            fprintf(fileId,'\tUTC End Time: %s\n\n',app.stopTime);
            fprintf(fileId,'\t𝜏_tc/tm: %d ms\n',app.T_tcmsEditField.Value);
            fprintf(fileId,'\tT_on: %d ms\n',app.T_onmsEditField.Value);
            fprintf(fileId,'\tT_off: %d ms\n',app.T_offmsEditField.Value);
            
            fprintf(fileId,'\tT_idle: %s minutes\n',app.T_idleminEditField.Value);
            fprintf(fileId,'\n--------------------------------------------------------------------------------------\n\n');

            startTime_index = strcmp(app.UITable.ColumnName, 'StartTime');
            EndTime_index = strcmp(app.UITable.ColumnName, 'EndTime');
            source_index = strcmp(app.UITable.ColumnName, 'Source');
            target_index = strcmp(app.UITable.ColumnName, 'Target');
            duration_index = strcmp(app.UITable.ColumnName, 'Duration');

            T_on = milliseconds(app.T_tcmsEditField.Value);
            T_tc = milliseconds(app.T_tcmsEditField.Value);
            T_idle = minutes(app.T_idleminEditField.Value);

            T0_time = datetime(app.startTime, 'Format', 'd-MMM-y HH:mm:ss.S','TimeZone','UTC');
            last_tc_received = T0_time;
            fprintf(fileId,'[T+00:00:00] Reference: %s\n\n', last_tc_received); 

            for i = 1:size(app.UITable.Data,1)

                startTimeData = app.UITable.Data{i, startTime_index}; 
                startTimeData = datetime(startTimeData, 'Format', 'd-MMM-y HH:mm:ss.S', 'TimeZone','UTC');

                EndTimeData = app.UITable.Data{i, EndTime_index}; 
                EndTimeData = datetime(EndTimeData, 'Format', 'd-MMM-y HH:mm:ss.S', 'TimeZone','UTC');

                source = app.UITable.Data{i, source_index};
                target = app.UITable.Data{i, target_index}; 
                Duration = seconds(app.UITable.Data{i, duration_index}); 
                
                new_tc_received = startTimeData;
 
                if minutes(new_tc_received - last_tc_received) > minutes(T_idle)
                    if i ~= 1
                        % Turn off ULTRON experiment
                        fprintf(fileId,'[T+%s] %s\tPLATFORM OPERATOR: Send TC_Off\n\n', last_tc_received-T0_time,last_tc_received);
                    end

                    % Turn on ULTRON experiment
                    fprintf(fileId,'[T+%s] %s\tPLATFORM OPERATOR: Send TC_On\n\n', (startTimeData -2*T_tc -T_on)-T0_time, startTimeData -2*T_tc -T_on);
                end
               
                if app.CommsUpLinkButton.Value == true 
                    fprintf(fileId,'[T+%s] %s\tPLATFORM OPERATOR: if TM_IdleMode received, then Send TC_StartRx\n\n',(startTimeData -T_tc)-T0_time,startTimeData -T_tc);

                    [gimbal, index] = getGimbalFromRFEquipment(app,source);
                    
                    if ~isempty(gimbal)
                        [az,el] = gimbalAngles(gimbal,startTimeData);
                        fprintf(fileId,'[T+%s] %s\tGS OPERATOR: Point "%s" gs gimbal to azimuth=%.3f° and elevation=%.3f°\n', startTimeData-T0_time,startTimeData, app.gs(index).Name, az, el);
                    end
    
                    fprintf(fileId,'[T+%s] %s\tGS OPERATOR: Start Tx in "%s" ground station\n', (startTimeData-T0_time),startTimeData, app.gs(index).Name);
                    fprintf(fileId,'\t\t\t> Uplink started between "%s" and "%s"\n', source, target);
                    fprintf(fileId,'\t\t\t> Window duration: %s\n\n', Duration);
                    
                    fprintf(fileId,'[T+%s] %s\tPLATFORM OPERATOR: if TM_RxMode received, then Send TC_EndRx\n\n',(EndTimeData -T_tc)-T0_time, EndTimeData -T_tc);
                    fprintf(fileId,'[T+%s] %s\tGS OPERATOR: End Rx in "%s" ground station\n\n', (EndTimeData-T0_time), EndTimeData, app.gs(index).Name);

                elseif app.CommsDownLinkButton.Value == true
                    fprintf(fileId,'%s\tPLATFORM OPERATOR: if TM_IdleMode received, then Send TC_StartTx\n\n',startTimeData -T_tc);

                    [gimbal, index] = getGimbalFromRFEquipment(app,target);
                    if ~isempty(gimbal)
                        [az,el] = gimbalAngles(gimbal,startTimeData);
                        fprintf(fileId,'%s\tGS OPERATOR: Point "%s" gs gimbal to azimuth=%.3f° and elevation=%.3f°\n', startTimeData, app.gs(index).Name, az, el);
                    end
    
                    fprintf(fileId,'%s\tGS OPERATOR: Start Rx in "%s" ground station\n', startTimeData, target);
                    fprintf(fileId,'\t\t\t> Downlink started between "%s" and "%s"\n', target, source);
                    fprintf(fileId,'\t\t\t> Window duration: %s\n\n', Duration);
                    
                    fprintf(fileId,'%s\tPLATFORM OPERATOR: if TM_TxMode received, then Send TC_EndTx\n\n',EndTimeData -T_tc);
                    fprintf(fileId,'%s\tGS OPERATOR: End Rx in "%s" ground station\n\n', EndTimeData, app.gs(index).Name);
                end

                last_tc_received = EndTimeData;
            end
 
            fprintf(fileId,'\n--------------------------------------------------------------------------------------\n\n');
            fprintf(fileId,'MISSION PLAN SUMMARY\n\n');

            fid=fopen(app.tleFile);
            fprintf(fileId,'\t> Orbital platform: \n\t\t%s\n',fgetl(fid));
            fclose(fid);

            fprintf(fileId,'\t> Ground stations: \n');
            for i = 2:size(app.raw,2)
                fprintf(fileId,'\t\t%s\n',app.raw{1,i});
            end

            fclose(fileId);

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.PAYLOADMISSIONPLANNERLabel = uilabel(app.UIFigure);
            app.PAYLOADMISSIONPLANNERLabel.Text = 'PAYLOAD MISSION PLANNER';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'logo.png');
            app.TabGroup = uitabgroup(app.UIFigure);
            app.MissionconfigurationTab = uitab(app.TabGroup);
            app.MissionconfigurationTab.Title = 'Mission configuration';
            app.MissionparametersLabel = uilabel(app.MissionconfigurationTab);
            app.MissionparametersLabel.Text = 'Mission parameters';
            app.OrbitalplatformdataLabel = uilabel(app.MissionconfigurationTab);
            app.OrbitalplatformdataLabel.Text = 'Orbital platform data';
            app.GroundstationsdataLabel = uilabel(app.MissionconfigurationTab);
            app.GroundstationsdataLabel.Text = 'Ground stations data';
            app.SelecttlefileButton = uibutton(app.MissionconfigurationTab, 'push');
            app.SelecttlefileButton.ButtonPushedFcn = createCallbackFcn(app, @SelecttlefileButtonPushed, true);
            app.SelecttlefileButton.Text = 'Select .tle file';
            app.tleInfo = uieditfield(app.MissionconfigurationTab, 'text');
            app.tleInfo.Enable = 'off';
            app.SelectxlsxfileButton = uibutton(app.MissionconfigurationTab, 'push');
            app.SelectxlsxfileButton.ButtonPushedFcn = createCallbackFcn(app, @SelectxlsxfileButtonPushed, true);
            app.SelectxlsxfileButton.Text = 'Select .xlsx file';
            app.gsInfo = uieditfield(app.MissionconfigurationTab, 'text');
            app.gsInfo.Enable = 'off';
            app.RunSimulationButton = uibutton(app.MissionconfigurationTab, 'push');
            app.RunSimulationButton.ButtonPushedFcn = createCallbackFcn(app, @RunSimulationButtonPushed, true);
            app.RunSimulationButton.Visible = 'off';
            app.RunSimulationButton.Text = 'Run Simulation';
            app.ButtonGroup = uibuttongroup(app.MissionconfigurationTab);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.ButtonGroup.ButtonDownFcn = createCallbackFcn(app, @ButtonGroupButtonDown, true);
            app.CommsUpLinkButton = uiradiobutton(app.ButtonGroup);
            app.CommsUpLinkButton.Text = 'Comms UpLink';
            app.CommsUpLinkButton.Value = true;
            app.CommsDownLinkButton = uiradiobutton(app.ButtonGroup);
            app.CommsDownLinkButton.Text = 'Comms DownLink';
            app.ExperimenttransmitterparametersLabel = uilabel(app.MissionconfigurationTab);
            app.ExperimenttransmitterparametersLabel.Visible = 'off';
            app.ExperimenttransmitterparametersLabel.Text = 'Experiment transmitter parameters';
            app.NameEditFieldLabel = uilabel(app.MissionconfigurationTab);
            app.NameEditFieldLabel.Text = 'Name';
            app.satNameTxRx = uieditfield(app.MissionconfigurationTab, 'text');
            app.satNameTxRx.Value = 'sat';
            app.MountingLocationmLabel = uilabel(app.MissionconfigurationTab);
            app.MountingLocationmLabel.Text = 'Mounting Location (m)';
            app.satMountingLocationTxRx = uieditfield(app.MissionconfigurationTab, 'text');
            app.satMountingLocationTxRx.Value = '[0;0;0]';
            app.satFrequencyTx = uieditfield(app.MissionconfigurationTab, 'text');
            app.satFrequencyTx.Visible = 'off';
            app.satFrequencyTx.Value = '1e9';
            app.FrequencyHzLabel = uilabel(app.MissionconfigurationTab);
            app.FrequencyHzLabel.Visible = 'off';
            app.FrequencyHzLabel.Text = 'Frequency (Hz)';
            app.BitRateMbpsLabel = uilabel(app.MissionconfigurationTab);
            app.BitRateMbpsLabel.Visible = 'off';
            app.BitRateMbpsLabel.Text = 'Bit Rate (Mbps)';
            app.satBitRateTx = uieditfield(app.MissionconfigurationTab, 'text');
            app.satBitRateTx.Visible = 'off';
            app.satBitRateTx.Value = '10';
            app.PowerdBWLabel = uilabel(app.MissionconfigurationTab);
            app.PowerdBWLabel.Visible = 'off';
            app.PowerdBWLabel.Text = 'Power (dBW)';
            app.satPowerTx = uieditfield(app.MissionconfigurationTab, 'text');
            app.satPowerTx.Visible = 'off';
            app.satPowerTx.Value = '12';
            app.MountingAnglesmLabel = uilabel(app.MissionconfigurationTab);
            app.MountingAnglesmLabel.Text = 'Mounting Angles (m)';
            app.satMountingAnglesTxRx = uieditfield(app.MissionconfigurationTab, 'text');
            app.satMountingAnglesTxRx.Value = '[0;0;0]';
            app.AntennaDropDownLabel = uilabel(app.MissionconfigurationTab);
            app.AntennaDropDownLabel.Text = 'Antenna';
            app.satAntenna = uidropdown(app.MissionconfigurationTab);
            app.satAntenna.Items = {'Gaussian Antenna'};
            app.satAntenna.Value = 'Gaussian Antenna';
            app.ExperimentreceiverparametersLabel = uilabel(app.MissionconfigurationTab);
            app.ExperimentreceiverparametersLabel.Text = 'Experiment receiver parameters';
            app.SystemLossdBEditFieldLabel = uilabel(app.MissionconfigurationTab);
            app.SystemLossdBEditFieldLabel.Text = 'System Loss (dB)';
            app.SystemLossRx = uieditfield(app.MissionconfigurationTab, 'text');
            app.SystemLossRx.Value = '5';
            app.PreReceiverLossdBEditFieldLabel = uilabel(app.MissionconfigurationTab);
            app.PreReceiverLossdBEditFieldLabel.Text = 'PreReceiver Loss (dB)';
            app.PreReceiverLossRx = uieditfield(app.MissionconfigurationTab, 'text');
            app.PreReceiverLossRx.Value = '3';
            app.GaintoNoiseTemperatureratiodBKLabel = uilabel(app.MissionconfigurationTab);
            app.GaintoNoiseTemperatureratiodBKLabel.Text = {'Gain to Noise '; 'Temperature ratio (dB/K)'};
            app.GaintoNoiseTemperatureratioRx = uieditfield(app.MissionconfigurationTab, 'text');
            app.GaintoNoiseTemperatureratioRx.Value = '3';
            app.RequiredEbNoMbpsEditFieldLabel = uilabel(app.MissionconfigurationTab);
            app.RequiredEbNoMbpsEditFieldLabel.Text = 'Required Eb/No (Mbps)';
            app.RequiredEbNoRx = uieditfield(app.MissionconfigurationTab, 'text');
            app.RequiredEbNoRx.Value = '10';
            app.DateDatePickerLabel = uilabel(app.MissionconfigurationTab);
            app.DateDatePickerLabel.Text = 'Date';
            app.DateDatePicker = uidatepicker(app.MissionconfigurationTab);
            app.DateDatePicker.Value = datetime([2024 3 6]);
            app.HourEditFieldLabel = uilabel(app.MissionconfigurationTab);
            app.HourEditFieldLabel.Text = 'Hour';
            app.HourEditField = uieditfield(app.MissionconfigurationTab, 'text');
            app.HourEditField.Value = '09:00:00';
            app.BeamApertureLabel = uilabel(app.MissionconfigurationTab);
            app.BeamApertureLabel.Text = 'Beam Aperture (º)';
            app.BeamAperture = uieditfield(app.MissionconfigurationTab, 'text');
            app.BeamAperture.Value = '70';
            app.T_onmsEditFieldLabel = uilabel(app.MissionconfigurationTab);
            app.T_onmsEditFieldLabel.Text = 'T_on (ms)';
            app.T_onmsEditField = uieditfield(app.MissionconfigurationTab, 'numeric');
            app.T_onmsEditField.Value = 100;
            app.T_tctmmsLabel = uilabel(app.MissionconfigurationTab);
            app.T_tctmmsLabel.Text = '𝜏_tc/tm (ms)';
            app.T_tcmsEditField = uieditfield(app.MissionconfigurationTab, 'numeric');
            app.T_tcmsEditField.Value = 200;
            app.T_offmsEditFieldLabel = uilabel(app.MissionconfigurationTab);
            app.T_offmsEditFieldLabel.Text = 'T_off (ms)';
            app.T_offmsEditField = uieditfield(app.MissionconfigurationTab, 'numeric');
            app.T_offmsEditField.Value = 100;
            app.T_idleminEditFieldLabel = uilabel(app.MissionconfigurationTab);
            app.T_idleminEditFieldLabel.Text = 'T_idle (min)';
            app.T_idleminEditField = uieditfield(app.MissionconfigurationTab, 'numeric');
            app.T_idleminEditField.Value = 10;
            app.OnboardexperimentparametersLabel = uilabel(app.MissionconfigurationTab);
            app.OnboardexperimentparametersLabel.Text = 'On-board experiment parameters';
            app.SimulationconfigurationTab = uitab(app.TabGroup);
            app.SimulationconfigurationTab.Title = 'Simulation configuration';
            app.SimulationrepresentationButtonGroup = uibuttongroup(app.SimulationconfigurationTab);
            app.SimulationrepresentationButtonGroup.Title = 'Simulation representation';
            app.Button_3D = uitogglebutton(app.SimulationrepresentationButtonGroup);
            app.Button_3D.Text = '3D';
            app.Button_3D.Value = true;
            app.Button_2D = uitogglebutton(app.SimulationrepresentationButtonGroup);
            app.Button_2D.Text = '2D';
            app.ShowgroundtrackCheckBox = uicheckbox(app.SimulationconfigurationTab);
            app.ShowgroundtrackCheckBox.Text = 'Show ground track';
            app.ShowgroundtrackCheckBox.Value = true;
            app.SimulationparametersLabel = uilabel(app.SimulationconfigurationTab);
            app.SimulationparametersLabel.Text = 'Simulation parameters';
            app.SampletimesEditFieldLabel = uilabel(app.SimulationconfigurationTab);
            app.SampletimesEditFieldLabel.Text = 'Sample time (s)';
            app.SampletimesEditField = uieditfield(app.SimulationconfigurationTab, 'text');
            app.SampletimesEditField.Value = '30';
            app.ScenaryDurationhEditFieldLabel = uilabel(app.SimulationconfigurationTab);
            app.ScenaryDurationhEditFieldLabel.Text = 'Scenary Duration (h)';
            app.ScenaryDurationhEditField = uieditfield(app.SimulationconfigurationTab, 'text');
            app.ScenaryDurationhEditField.Value = '20';
            app.OtherparametersLabel = uilabel(app.SimulationconfigurationTab);
            app.OtherparametersLabel.Text = 'Other parameters';
            app.FocusscenarycamerainorbitalplatformCheckBox = uicheckbox(app.SimulationconfigurationTab);
            app.FocusscenarycamerainorbitalplatformCheckBox.Text = 'Focus scenary camera in orbital platform';
            app.ShowantennaradiationpatternsCheckBox = uicheckbox(app.SimulationconfigurationTab);
            app.ShowantennaradiationpatternsCheckBox.Text = 'Show antenna radiation patterns';
            app.ResultsTab = uitab(app.TabGroup);
            app.ResultsTab.Title = 'Results';
            app.UITable = uitable(app.ResultsTab);
            app.UITable.ColumnName = {'asd'; 'asd'; 'asd'; 'asd'};
            app.UITable.RowName = {};
            app.UITable.Visible = 'off';
            app.ExporttoxlsxfileButton = uibutton(app.ResultsTab, 'push');
            app.ExporttoxlsxfileButton.ButtonPushedFcn = createCallbackFcn(app, @ExporttoxlsxfileButtonPushed, true);
            app.ExporttoxlsxfileButton.Visible = 'off';
            app.ExporttoxlsxfileButton.Text = 'Export to .xlsx file';
            app.ExporttocsvfileButton = uibutton(app.ResultsTab, 'push');
            app.ExporttocsvfileButton.ButtonPushedFcn = createCallbackFcn(app, @ExporttocsvfileButtonPushed, true);
            app.ExporttocsvfileButton.Visible = 'off';
            app.ExporttocsvfileButton.Text = 'Export to .csv file';
            app.GeneratemissionplanButton = uibutton(app.ResultsTab, 'push');
            app.GeneratemissionplanButton.ButtonPushedFcn = createCallbackFcn(app, @GeneratemissionplanButtonPushed, true);
            app.GeneratemissionplanButton.Visible = 'off';
            app.GeneratemissionplanButton.Text = 'Generate mission plan';
            app.InformationTab = uitab(app.TabGroup);
            app.InformationTab.Title = 'Information';
            app.AuthorCsarBoraoMoratinosLabel = uilabel(app.InformationTab);
            app.AuthorCsarBoraoMoratinosLabel.Text = 'Author: César Borao Moratinos';
            app.DegreeAerospaceEngineeringinNavigationLabel = uilabel(app.InformationTab);
            app.DegreeAerospaceEngineeringinNavigationLabel.Text = 'Degree: Aerospace Engineering in Navigation';
            app.SoftwareVersionv12Label = uilabel(app.InformationTab);
            app.SoftwareVersionv12Label.Text = 'Software Version: v1.2';
            app.ContextMenu = uicontextmenu(app.UIFigure);
            app.Menu = uimenu(app.ContextMenu);
            app.Menu.Text = 'Menu';
            app.Menu2 = uimenu(app.ContextMenu);
            app.Menu2.Text = 'Menu2';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = mission_planning

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end